classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        Tab                       matlab.ui.container.Tab
        Panel_2                   matlab.ui.container.Panel
        Knob                      matlab.ui.control.DiscreteKnob
        IGauge                    matlab.ui.control.LinearGauge
        AGaugeLabel               matlab.ui.control.Label
        Panel                     matlab.ui.container.Panel
        Lamp                      matlab.ui.control.Lamp
        Label_3                   matlab.ui.control.Label
        MainSwitch                matlab.ui.control.Switch
        Label_2                   matlab.ui.control.Label
        Tab_2                     matlab.ui.container.Tab
        Ymax                      matlab.ui.control.NumericEditField
        EditField_3Label_2        matlab.ui.control.Label
        Ymin                      matlab.ui.control.NumericEditField
        Label_7                   matlab.ui.control.Label
        Label_6                   matlab.ui.control.Label
        Label_4                   matlab.ui.control.Label
        Xmax                      matlab.ui.control.NumericEditField
        EditField_3Label          matlab.ui.control.Label
        Xmin                      matlab.ui.control.NumericEditField
        Label_5                   matlab.ui.control.Label
        ValiButton                matlab.ui.control.Button
        Unit1                     matlab.ui.control.Label
        EditField                 matlab.ui.control.EditField
        Label                     matlab.ui.control.Label
        TheoPlot                  matlab.ui.control.Button
        ExpPlot                   matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        Tab_3                     matlab.ui.container.Tab
        CalP3                     matlab.ui.container.Panel
        CalWC                     matlab.ui.control.Button
        mmcdotKLabel              matlab.ui.control.Label
        EditWC                    matlab.ui.control.NumericEditField
        Label_9                   matlab.ui.control.Label
        WFormula                  matlab.ui.control.Label
        Unit4_2                   matlab.ui.control.Label
        EditWT                    matlab.ui.control.NumericEditField
        TEditFieldLabel_3         matlab.ui.control.Label
        Unit4_1                   matlab.ui.control.Label
        EditLm                    matlab.ui.control.NumericEditField
        lambda_maxEditFieldLabel  matlab.ui.control.Label
        CalP2                     matlab.ui.container.Panel
        Unit3_4                   matlab.ui.control.Label
        T4Label_2                 matlab.ui.control.Label
        EditDelta                 matlab.ui.control.NumericEditField
        CalSB                     matlab.ui.control.Button
        SBFormula                 matlab.ui.control.Label
        Unit3_3                   matlab.ui.control.Label
        Unit3_2                   matlab.ui.control.Label
        Unit3_1                   matlab.ui.control.Label
        TotalRa                   matlab.ui.control.NumericEditField
        RLabel                    matlab.ui.control.Label
        EditSBT_2                 matlab.ui.control.NumericEditField
        T4Label                   matlab.ui.control.Label
        EditSBT                   matlab.ui.control.NumericEditField
        TEditFieldLabel_2         matlab.ui.control.Label
        CalP1                     matlab.ui.container.Panel
        Unit2_4                   matlab.ui.control.Label
        Unit2_3                   matlab.ui.control.Label
        DisEe                     matlab.ui.control.NumericEditField
        ETLabel                   matlab.ui.control.Label
        DisEt                     matlab.ui.control.NumericEditField
        ETEditFieldLabel          matlab.ui.control.Label
        EditPT                    matlab.ui.control.NumericEditField
        TEditFieldLabel           matlab.ui.control.Label
        EditPL                    matlab.ui.control.NumericEditField
        EditField_3Label_3        matlab.ui.control.Label
        Label_8                   matlab.ui.control.Label
        PFormula                  matlab.ui.control.Label
        CalPlank                  matlab.ui.control.Button
        Unit2_2                   matlab.ui.control.Label
        Unit2_1                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        FlagE=0 % 检验是否测量过黑体辐射实验曲线的标记值
        %实验数据文件名
        File="Data\"+["E2270K.txt" "E2330K.txt" "E2360K.txt" ...
            "E2400K.txt" "E2440K.txt" "E2500K.txt" "E2580K.txt"...
            "E2660K.txt" "E2750K.txt" "E2860K.txt" "E2940K.txt"];
        AxisLim=[800,2500,0,6000];%坐标轴范围
        E_e % 黑体辐射能量密度的实验值
    end
    
    methods (Access = private)
        
        function E_t = CalEth(~,lambda,T) %计算能量密度的理论值的函数
            %物理常数
            h=6.62607015*10^-34;
            c=299792458;
            k=1.380649*10^-23;
            %经验参数
            c1=2*pi*h*c^2;
            c2=h*c/k;
            %自变量
            Lambda=lambda*10^-9;%单位m
            %普朗克公式的代码形式
            E_t=(c1./power(Lambda,5)).*...
                (1./(exp(c2./(Lambda.*T))-1))*10^-9;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %设置图窗参数
            axis(app.UIAxes,app.AxisLim);%坐标范围
            grid(app.UIAxes,"on");%网格
            title(app.UIAxes,"");%图像标题
            xlabel(app.UIAxes,"波长\lambda(nm)");%横坐标名
            ylabel(app.UIAxes,"辐射密度E(\lambda,T)(W/mm^3)");%纵坐标名
        end

        % Button pushed function: ExpPlot
        function ExpPlotPushed(app, event)
            %绘制实验曲线
            if strcmp(app.MainSwitch.Value,'Off')
                msgbox("实验仪器电源未打开，请到控制区将仪器开关置于" + ...
                    "On","Error","error");
            else
                value = app.EditField.Value;%输入温度
                file_n=app.Knob.Value;%根据溴钨灯电流大小寻找合适的数据
                s1="Data\E"+value+"K.txt";
                s2=app.File(file_n);
                if strcmp(s1,s2)==0%检测输入温度数值是否正确
                    msgbox("您输入的温度数值与调节的溴钨灯电流大小" + ...
                        "不对应，请重新输入","Error","error");
                else
                    app.FlagE=0;%在绘制之前设值为0
                    %载入第file_n个实验数据
                    Lab_Data=load(app.File(file_n));
                    lambda=Lab_Data(:,1);%横坐标
                    app.E_e=Lab_Data(:,2);%纵坐标,能量密度

                    %动态实验曲线绘制
                    cla(app.UIAxes);%清理曲线

                    for j=2:length(lambda)
                        hold(app.UIAxes,"on");
                        i=j-1;
                        plot(app.UIAxes,lambda(i:j),app.E_e(i:j),'-g');
                        pause(0.0001);
                    end
                    app.FlagE=1;%绘制之后标记值改变
                end
            end
        end

        % Button pushed function: TheoPlot
        function TheoPlotButtonPushed(app, event)
           %绘制理论曲线，先检测总开关是否打开
            if strcmp(app.MainSwitch.Value,'Off')
                msgbox("实验仪器电源未打开，请到控制区将仪器开" + ...
                    "关置于On","Error","error");
            else
                %理论曲线
                value = app.EditField.Value;%输入温度
                T=str2double(value);%将输入温度的形式从字符转化为数值
                lambda=800:1:2500;%单位nm设置波长范围
                E_t=app.CalEth(lambda,T);

                %绘图
                plot(app.UIAxes,lambda,E_t,'-r');
            end
        end

        % Value changed function: MainSwitch
        function MainSwitchValueChanged(app, event)
            value = app.MainSwitch.Value;
            if strcmp(value,'Off')%当总开关关闭时指示灯不亮
                app.Lamp.Color=[0 0 0];
            else%当总开关打开时指示灯亮起
                app.Lamp.Color=[0 1 0];
            end
        end

        % Value changed function: Knob
        function KnobValueChanged(app, event)
            value = app.Knob.Value;
            if strcmp(app.MainSwitch.Value,'Off')%检验总开关是否打开
                msgbox("实验仪器电源未打开，请到控制区将仪器开关" + ...
                    "置于On","non-modal","Error","error");
            else
                app.IGauge.Value=value;
            end
        end

        % Value changed function: Xmin
        function XminValueChanged(app, event)
            value = app.Xmin.Value;
            app.AxisLim(1)=value;%将横坐标最小值设为输入值
            axis(app.UIAxes,app.AxisLim)%刷新坐标范围
        end

        % Value changed function: Xmax
        function XmaxValueChanged(app, event)
            value = app.Xmax.Value;
            app.AxisLim(2)=value;%将横坐标最大值设为输入值
            axis(app.UIAxes,app.AxisLim)%刷新坐标范围
        end

        % Value changed function: Ymin
        function YminValueChanged(app, event)
            value = app.Ymin.Value;
            app.AxisLim(3)=value;%将纵坐标最小值设为输入值
            axis(app.UIAxes,app.AxisLim)%刷新坐标范围
        end

        % Value changed function: Ymax
        function YmaxValueChanged(app, event)
            value = app.Ymax.Value;
            app.AxisLim(4)=value;%将纵坐标最大值设为输入值
            axis(app.UIAxes,app.AxisLim)%刷新坐标范围
        end

        % Callback function: Tab_3, ValiButton
        function Tab_3ButtonDown(app, event)
            if strcmp(app.MainSwitch.Value,'Off')%检验总开关是否打开
                msgbox("实验仪器电源未打开，请到控制区将仪器开关" + ...
                    "置于On","non-modal","Error","error");
                app.TabGroup.SelectedTab=app.Tab_2;

            elseif app.FlagE==0%检验是否否测量过黑体辐射实验曲线
                msgbox("未测量实验数据，不能进行黑体辐射定律" + ...
                    "的验证，请先测量实验数据","Error","error");
                app.TabGroup.SelectedTab=app.Tab_2;

            else%跳转到计算区进行黑体辐射定律的验证
                app.TabGroup.SelectedTab=app.Tab_3;
                %初始化已知数据
                app.EditPT.Value=str2double(app.EditField.Value);
                app.EditSBT.Value=str2double(app.EditField.Value);
                app.EditWT.Value=str2double(app.EditField.Value);
            end
        end

        % Button pushed function: CalPlank
        function CalPlankButtonPushed(app, event)
            %计算输入波长对应的能量密度
            lambda=app.EditPL.Value;%输入波长
            T=app.EditPT.Value;%输入温度
            if lambda<800||lambda>2500%检测输入值是否在测量范围内
                msgbox("您输入的波长超出测量范围，请重新输入","Error", ...
                    "error");
            else
                n=lambda-799;%在全部数据中波长所对应能量密度的位置
                E_t=app.CalEth(lambda,T);%计算理论值
                %输出实验值和理论值
                app.DisEe.Value=app.E_e(n);
                app.DisEt.Value=E_t;
            end
        end

        % Button pushed function: CalSB
        function CalSBButtonPushed(app, event)
            %计算斯特潘-玻尔兹曼常量的实验值
            app.EditSBT_2.Value=power(app.EditSBT.Value,4);%计算T的4次方

            %计算实验温度下的总辐出度
            T=app.EditSBT.Value;%实验温度

            %使用理论值补全波长小于800nm，大于2500nm时的能量密度
            Eel=app.CalEth(0:799,T);%波长小于800时的能量密度理论值
            Eem=transpose(app.E_e);%实验值
            Eer=app.CalEth(2501:10000,T);%波长大于2500时的能量密度理论值
            Ee=[Eel Eem Eer];%补全后的实验值
            Ee(1)=0;

            %使用数值积分法计算总能量总辐出度
            R=0;
            for i=1:10000
                R=R+1*Ee(i);
            end
            app.TotalRa.Value=R;%输出总辐出度
            %计算并输出斯特潘-玻尔兹曼常数
            app.EditDelta.Value=R/app.EditSBT_2.Value;

        end

        % Button pushed function: CalWC
        function CalWCButtonPushed(app, event)
            %计算维恩常量
            lambda=800:2500;
            T=app.EditWT.Value;%输入温度
            Ee=app.E_e;
            %删去误差过大的数据
            for i=1:3
                Ee(i)=Ee(4);
            end
            [~, idx]=max(Ee);
            lmax=lambda(idx);%最大值对应的波长

            %输出能量密度最大值对应的波长
            app.EditLm.Value=lmax;
            %计算并输出维恩常量
            app.EditWC.Value=lmax*T*10^-6;%单位mm*K
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '控制区';
            app.Panel = uipanel(app.Tab);
            app.Panel.Title = '黑体辐射实验仪电源';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '仪器开关';
            app.MainSwitch = uiswitch(app.Panel, 'slider');
            app.MainSwitch.ValueChangedFcn = createCallbackFcn(app, @MainSwitchValueChanged, true);
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '电源指示灯';
            app.Lamp = uilamp(app.Panel);
            app.Panel_2 = uipanel(app.Tab);
            app.Panel_2.Title = '溴钨灯电源';
            app.AGaugeLabel = uilabel(app.Panel_2);
            app.AGaugeLabel.Text = '电流(A)';
            app.IGauge = uigauge(app.Panel_2, 'linear');
            app.IGauge.Limits = [1 11];
            app.IGauge.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11];
            app.IGauge.MajorTickLabels = {'1.4', '1.5', '1.6', '1.7', '1.8', '1.9', '2.0', '2.1', '2.2', '2.3', '2.5'};
            app.IGauge.MinorTicks = [];
            app.Knob = uiknob(app.Panel_2, 'discrete');
            app.Knob.Items = {'1.4', '1.5', '1.6', '1.7', '1.8', '1.9', '2.0', '2.1', '2.2', '2.3', '2.5'};
            app.Knob.ItemsData = [1 2 3 4 5 6 7 8 9 10 11];
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged, true);
            app.Knob.Value = 1;
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '测量区';
            app.UIAxes = uiaxes(app.Tab_2);
            zlabel(app.UIAxes, 'Z')
            app.ExpPlot = uibutton(app.Tab_2, 'push');
            app.ExpPlot.ButtonPushedFcn = createCallbackFcn(app, @ExpPlotPushed, true);
            app.ExpPlot.Text = '测量实验曲线';
            app.TheoPlot = uibutton(app.Tab_2, 'push');
            app.TheoPlot.ButtonPushedFcn = createCallbackFcn(app, @TheoPlotButtonPushed, true);
            app.TheoPlot.Text = '理论曲线';
            app.Label = uilabel(app.Tab_2);
            app.Label.Text = '黑体温度';
            app.EditField = uieditfield(app.Tab_2, 'text');
            app.Unit1 = uilabel(app.Tab_2);
            app.Unit1.Text = 'K';
            app.ValiButton = uibutton(app.Tab_2, 'push');
            app.ValiButton.ButtonPushedFcn = createCallbackFcn(app, @Tab_3ButtonDown, true);
            app.ValiButton.Text = '验证黑体辐射定律';
            app.Label_5 = uilabel(app.Tab_2);
            app.Label_5.Text = '起始波长';
            app.Xmin = uieditfield(app.Tab_2, 'numeric');
            app.Xmin.ValueChangedFcn = createCallbackFcn(app, @XminValueChanged, true);
            app.Xmin.Value = 800;
            app.EditField_3Label = uilabel(app.Tab_2);
            app.EditField_3Label.Text = '终止波长';
            app.Xmax = uieditfield(app.Tab_2, 'numeric');
            app.Xmax.ValueChangedFcn = createCallbackFcn(app, @XmaxValueChanged, true);
            app.Xmax.Value = 2500;
            app.Label_4 = uilabel(app.Tab_2);
            app.Label_4.Text = {'工作'; '范围'};
            app.Label_6 = uilabel(app.Tab_2);
            app.Label_6.Text = {'能量'; '密度'};
            app.Label_7 = uilabel(app.Tab_2);
            app.Label_7.Text = '最小值';
            app.Ymin = uieditfield(app.Tab_2, 'numeric');
            app.Ymin.ValueChangedFcn = createCallbackFcn(app, @YminValueChanged, true);
            app.EditField_3Label_2 = uilabel(app.Tab_2);
            app.EditField_3Label_2.Text = '最大值';
            app.Ymax = uieditfield(app.Tab_2, 'numeric');
            app.Ymax.ValueChangedFcn = createCallbackFcn(app, @YmaxValueChanged, true);
            app.Ymax.Value = 6000;
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '计算区';
            app.Tab_3.ButtonDownFcn = createCallbackFcn(app, @Tab_3ButtonDown, true);
            app.CalP1 = uipanel(app.Tab_3);
            app.CalP1.Title = '普朗克辐射定律';
            app.Unit2_1 = uilabel(app.CalP1);
            app.Unit2_1.Interpreter = 'latex';
            app.Unit2_1.Text = '$nm$';
            app.Unit2_2 = uilabel(app.CalP1);
            app.Unit2_2.Interpreter = 'latex';
            app.Unit2_2.Text = '$K$';
            app.CalPlank = uibutton(app.CalP1, 'push');
            app.CalPlank.ButtonPushedFcn = createCallbackFcn(app, @CalPlankButtonPushed, true);
            app.CalPlank.Text = '计算';
            app.PFormula = uilabel(app.CalP1);
            app.PFormula.Interpreter = 'latex';
            app.PFormula.Text = {'将该点波长带入公式'; '$E(\lambda,T)=\frac{C_1}{\lambda^5(e^{\frac{C_2}{\lambda T}}-1)}$'};
            app.Label_8 = uilabel(app.CalP1);
            app.Label_8.Text = '';
            app.EditField_3Label_3 = uilabel(app.CalP1);
            app.EditField_3Label_3.Text = '波长λ';
            app.EditPL = uieditfield(app.CalP1, 'numeric');
            app.EditPL.ValueDisplayFormat = '%.0f';
            app.TEditFieldLabel = uilabel(app.CalP1);
            app.TEditFieldLabel.Text = '光源色温T';
            app.EditPT = uieditfield(app.CalP1, 'numeric');
            app.EditPT.Editable = 'off';
            app.ETEditFieldLabel = uilabel(app.CalP1);
            app.ETEditFieldLabel.Text = '理论E(λ,T)';
            app.DisEt = uieditfield(app.CalP1, 'numeric');
            app.DisEt.Limits = [0 Inf];
            app.DisEt.Editable = 'off';
            app.ETLabel = uilabel(app.CalP1);
            app.ETLabel.Text = '实测E(λ,T)';
            app.DisEe = uieditfield(app.CalP1, 'numeric');
            app.DisEe.Editable = 'off';
            app.Unit2_3 = uilabel(app.CalP1);
            app.Unit2_3.Interpreter = 'latex';
            app.Unit2_3.Text = '$W/mm^3$';
            app.Unit2_4 = uilabel(app.CalP1);
            app.Unit2_4.Interpreter = 'latex';
            app.Unit2_4.Text = '$W/mm^3$';
            app.CalP2 = uipanel(app.Tab_3);
            app.CalP2.Title = '斯特潘-玻尔兹曼定律';
            app.TEditFieldLabel_2 = uilabel(app.CalP2);
            app.TEditFieldLabel_2.Text = '光源色温T';
            app.EditSBT = uieditfield(app.CalP2, 'numeric');
            app.EditSBT.Editable = 'off';
            app.T4Label = uilabel(app.CalP2);
            app.T4Label.Interpreter = 'latex';
            app.T4Label.Text = '$T^4$';
            app.EditSBT_2 = uieditfield(app.CalP2, 'numeric');
            app.EditSBT_2.Editable = 'off';
            app.RLabel = uilabel(app.CalP2);
            app.RLabel.Text = '总辐出度R';
            app.TotalRa = uieditfield(app.CalP2, 'numeric');
            app.TotalRa.Editable = 'off';
            app.Unit3_1 = uilabel(app.CalP2);
            app.Unit3_1.Interpreter = 'latex';
            app.Unit3_1.Text = '$K$';
            app.Unit3_2 = uilabel(app.CalP2);
            app.Unit3_2.Interpreter = 'latex';
            app.Unit3_2.Text = '$K^4$';
            app.Unit3_3 = uilabel(app.CalP2);
            app.Unit3_3.Interpreter = 'latex';
            app.Unit3_3.Text = '$W/mm^2$';
            app.SBFormula = uilabel(app.CalP2);
            app.SBFormula.Interpreter = 'latex';
            app.SBFormula.Text = {'斯特潘玻尔兹曼常数$\delta=\frac{R}{T^4}$'; '理论值$\delta=5.670\times10^{-4}W/(mm^2\cdot K^4)$'};
            app.CalSB = uibutton(app.CalP2, 'push');
            app.CalSB.ButtonPushedFcn = createCallbackFcn(app, @CalSBButtonPushed, true);
            app.CalSB.Text = '计算';
            app.EditDelta = uieditfield(app.CalP2, 'numeric');
            app.EditDelta.Editable = 'off';
            app.T4Label_2 = uilabel(app.CalP2);
            app.T4Label_2.Interpreter = 'latex';
            app.T4Label_2.Text = '实验值$\delta$';
            app.Unit3_4 = uilabel(app.CalP2);
            app.Unit3_4.Interpreter = 'latex';
            app.Unit3_4.Text = '$W/(mm^2\cdot K^4)$';
            app.CalP3 = uipanel(app.Tab_3);
            app.CalP3.Title = '维恩位移定律';
            app.lambda_maxEditFieldLabel = uilabel(app.CalP3);
            app.lambda_maxEditFieldLabel.Interpreter = 'latex';
            app.lambda_maxEditFieldLabel.Text = '最大能量密度对应的波长$\lambda_{max}$';
            app.EditLm = uieditfield(app.CalP3, 'numeric');
            app.EditLm.Editable = 'off';
            app.Unit4_1 = uilabel(app.CalP3);
            app.Unit4_1.Interpreter = 'latex';
            app.Unit4_1.Text = '$nm$';
            app.TEditFieldLabel_3 = uilabel(app.CalP3);
            app.TEditFieldLabel_3.Text = '光源色温T';
            app.EditWT = uieditfield(app.CalP3, 'numeric');
            app.EditWT.Editable = 'off';
            app.Unit4_2 = uilabel(app.CalP3);
            app.Unit4_2.Interpreter = 'latex';
            app.Unit4_2.Text = '$K$';
            app.WFormula = uilabel(app.CalP3);
            app.WFormula.Interpreter = 'latex';
            app.WFormula.Text = {'维恩常量$A=\lambda T$'; '理论值$A=2.896mm\cdot K$'};
            app.Label_9 = uilabel(app.CalP3);
            app.Label_9.Text = '维恩位移常量实验值';
            app.EditWC = uieditfield(app.CalP3, 'numeric');
            app.EditWC.Editable = 'off';
            app.mmcdotKLabel = uilabel(app.CalP3);
            app.mmcdotKLabel.Interpreter = 'latex';
            app.mmcdotKLabel.Text = '$mm\cdot K$';
            app.CalWC = uibutton(app.CalP3, 'push');
            app.CalWC.ButtonPushedFcn = createCallbackFcn(app, @CalWCButtonPushed, true);
            app.CalWC.Text = '计算';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end